classdef cluster_sample < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ClustersamplingUIFigure         matlab.ui.Figure
        SelectdataPanel                 matlab.ui.container.Panel
        AddfileButton                   matlab.ui.control.Button
        AddfolderButton                 matlab.ui.control.Button
        RemoveselectedButton            matlab.ui.control.Button
        DatasetstatisticsPanel          matlab.ui.container.Panel
        NumberofframesNALabel           matlab.ui.control.Label
        ImagesizeNALabel                matlab.ui.control.Label
        NumberofvideosNALabel           matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
        SelectallButton                 matlab.ui.control.Button
        ClustersamplingPanel            matlab.ui.container.Panel
        PCAPreprocessingPanel           matlab.ui.container.Panel
        ComputePCAButton                matlab.ui.control.Button
        UsetheseprojectionsforclusteringCheckBox  matlab.ui.control.CheckBox
        PlotvarianceexplainedButton     matlab.ui.control.Button
        PCstoprojectdatadowntoEditFieldLabel  matlab.ui.control.Label
        PCstoprojectdataontoEditField   matlab.ui.control.NumericEditField
        PloteigenmodesButton            matlab.ui.control.Button
        PCALabel                        matlab.ui.control.Label
        ClusteringPanel                 matlab.ui.container.Panel
        PlotclustercentroidsButton      matlab.ui.control.Button
        NumberofclustersEditFieldLabel  matlab.ui.control.Label
        NumberofclustersEditField       matlab.ui.control.NumericEditField
        ComputeclustersButton           matlab.ui.control.Button
        ClusteringLabel                 matlab.ui.control.Label
        PlotdistributionButton          matlab.ui.control.Button
        ButtonGroup                     matlab.ui.container.ButtonGroup
        ClusterorderButton              matlab.ui.control.RadioButton
        ShuffledButton                  matlab.ui.control.RadioButton
        SaveButton                      matlab.ui.control.Button
        SamplesperclusterEditFieldLabel  matlab.ui.control.Label
        SamplesperclusterEditField      matlab.ui.control.NumericEditField
        SampleorderLabel                matlab.ui.control.Label
        PreviewButton                   matlab.ui.control.Button
        GenerateddatasetLabel           matlab.ui.control.Label
        SamplespervideoEditFieldLabel   matlab.ui.control.Label
        SamplespervideoEditField        matlab.ui.control.NumericEditField
        LoadsamplesButton               matlab.ui.control.Button
        InitialSamplesLabel             matlab.ui.control.Label
        ParallelCheckBox                matlab.ui.control.CheckBox
    end


    properties (Access = private)
        boxPaths % paths to loaded data
        box % stack of images
        dataIdx % indices of sampled data
        exptID % indices of path to data source
        pcs = [] % PCA data
        G % grouping variable based on clustering
        cluster_centroids % cluster centroids
    end

    methods (Access = private)
    
        function addData(app, paths)
            if isempty(paths); return; end
            if ~iscell(paths); paths = {paths}; end
            
            % Get current table
            currentPaths = {};
            T = app.getDataTable();
            if ~isempty(T); currentPaths = T.path; end
            
            % Scan for valid paths
            validPaths = {};
            dsets = {};
            shapes = {};
            dset_info = {};
            for i = 1:numel(paths)
                try
                    info = h5info(paths{i},'/box');
                    if numel(info.Dataspace.Size) == 4 && ~ismember(paths(i), currentPaths)
                        validPaths{end+1} = paths{i};
                        dsets{end+1} = '/box';
                        shapes{end+1} = mat2str(info.Dataspace.Size);
                        dset_info{end+1} = info;
                    end
                catch
                    continue
                end
            end
            
            % Add to UI
            new_rows = [num2cell(false(numel(validPaths),1)), validPaths(:), dsets(:), shapes(:)];
            if size(app.UITable.Data,1) > 0
                app.UITable.Data = [app.UITable.Data; new_rows];
                app.UITable.UserData.dset_info = [app.UITable.UserData.dset_info(:); dset_info(:)];
            else
                app.UITable.Data = new_rows;
                app.UITable.UserData.dset_info = dset_info(:);
            end
            
            % Update stats
            app.updateDataStats();
        end
        
        function updateDataStats(app)
            if size(app.UITable.Data,1) == 0
                app.NumberofvideosNALabel.Text = 'Number of videos: N/A';
                app.NumberofframesNALabel.Text = 'Number of frames: N/A';
                app.ImagesizeNALabel.Text = 'Image size: N/A';
            else
                T = app.getDataTable();
                app.NumberofvideosNALabel.Text = sprintf('Number of videos: %d', height(T));
                app.NumberofframesNALabel.Text = sprintf('Number of frames: %d', sum(cellfun(@(x)x(4),T.shape)));
                app.ImagesizeNALabel.Text = ['Image size: ' mat2str(T.shape{1}(1:3))];
                app.ImagesizeNALabel.FontWeight = 'normal';
                
                % Warn if data has variable shape
                shapes = cf(@(x)x(1:3),T.shape);
                allSameShape = all(cellfun(@(x)isequal(x,shapes{1}),shapes));
                if ~allSameShape
                    app.ImagesizeNALabel.Text = [app.ImagesizeNALabel.Text ' (Warning: not all datasets have the same shape!)'];
                    app.ImagesizeNALabel.FontWeight = 'bold';
                end
            end
        end
        
        function T = getDataTable(app)
            T = [];
            if size(app.UITable.Data,1) == 0; return; end
            
            T = cell2table(app.UITable.Data, 'VariableNames',{'selected','path','dataset','shape'});
            T.shape = cf(@(x)x.Dataspace.Size, app.UITable.UserData.dset_info);
        end
        
    end

    methods (Access = public)
    
        function [box, dataIdx] = loadBox(app, boxPath, dset, numSamples, randomized)
            if nargin < 5; randomized = false; end
            
            % Get number of frames
            numFrames = h5size(boxPath, dset, 4);
            numSamples = min(numSamples,numFrames);
            
            % Sample subset
            if randomized
                % Simple random
                dataIdx = sort(randperm(numFrames,numSamples));
            else
                % Strided in time
                stride = floor(numFrames/numSamples);
                dataIdx = 1:stride:numFrames;
            end
            dataIdx = dataIdx(:);
            
            % Load
            box = h5readframes(boxPath, dset, dataIdx);
        end
        
        function sampledIdx = sampleOverClusters(app, G, n)
            sampledIdx = grp2cell(1:numel(G),G); % group by cluster
            assert(all(cellfun(@numel,sampledIdx) >= n)) % make sure we have enough samples in each cluster
            sampledIdx = cf(@(x)datasample(x,n,'Replace',false),sampledIdx); % resample
            sampledIdx = cellcat(sampledIdx); % concatenate with one cluster per row
            sampledIdx = sampledIdx(:); % flatten to cycle through clusters sequentially
        end
        
    end


    methods (Access = private)

        % Button pushed function: AddfileButton
        function AddfileButtonPushed(app, event)
            try
                path = uibrowse('*.h5',[],'Select an HDF5 file with a video dataset...','file');
                app.addData(path);
            catch
            end
        end

        % Button pushed function: AddfolderButton
        function AddfolderButtonPushed(app, event)
            try
                folderPath = uibrowse([],[],'Select a folder with HDF5 files...','dir');
                paths = dir_ext(folderPath,'h5',true);
                app.addData(paths);
            catch
            end
        end

        % Button pushed function: SelectallButton
        function SelectallButtonPushed(app, event)
            if size(app.UITable.Data,1) == 0; return; end
            selected = cellcat(app.UITable.Data(:,1),1);
            
            if all(selected)
                selected(:) = false;
            else
                selected(:) = true;
            end
            app.UITable.Data(:,1) = num2cell(selected);
        end

        % Button pushed function: RemoveselectedButton
        function RemoveselectedButtonPushed(app, event)
            if size(app.UITable.Data,1) == 0; return; end
            selected = cellcat(app.UITable.Data(:,1),1);
            if ~any(selected); return; end
            
            % Remove data
            app.UITable.Data = app.UITable.Data(~selected,:);
            app.UITable.UserData.dset_info = app.UITable.UserData.dset_info(~selected);
            
            % Update stats
            app.updateDataStats();
        end

        % Button pushed function: ComputePCAButton
        function ComputePCAButtonPushed(app, event)
            % Compute PCA
            t0 = stic;
            app.pcs = ezpca(single(stack2vecs(app.box)));
            app.pcs.coeff_box = vecs2stack(app.pcs.coeff',size(app.box(:,:,:,1)));
            elapsed = stoc(t0);
            
            % Enable options
            app.PCALabel.Enable = 'on';
            app.PCALabel.Text = sprintf('Computed PCA in %.1fs.\n90%% of variance explained in %d modes.', elapsed, find(cumsum(app.pcs.explained) >= 90,1));
            app.PlotvarianceexplainedButton.Enable = 'on';
            app.PloteigenmodesButton.Enable = 'on';
            app.PCstoprojectdatadowntoEditFieldLabel.Enable = 'on';
            app.PCstoprojectdataontoEditField.Enable = 'on';
            app.UsetheseprojectionsforclusteringCheckBox.Enable = 'on';
        end

        % Button pushed function: LoadsamplesButton
        function LoadsamplesButtonPushed(app, event)
            % Get dataset info
            T = app.getDataTable();
            if isempty(T); warndlg('No data has been selected yet.'); return; end
            
            % Initialize
            t0 = stic;
            boxPaths = T.path;
            dsets = T.dataset;
            [box,dataIdx] = cell1(numel(boxPaths));
            n = app.SamplespervideoEditField.Value;
            
            % Load boxes
            if app.ParallelCheckBox.Value
                parfor i = 1:numel(boxPaths)
                    [box{i}, dataIdx{i}] = loadBox(app, boxPaths{i}, dsets{i}, n);
                end
            else
                for i = 1:numel(boxPaths)
                    [box{i}, dataIdx{i}] = loadBox(app, boxPaths{i}, dsets{i}, n);
                end
            end
            
            % Store
            [app.box,app.exptID] = cellcat(box,4);
            app.dataIdx = cellcat(dataIdx,1);
            app.boxPaths = boxPaths;
            
            % Update GUI
            elapsed = stoc(t0);
            app.InitialSamplesLabel.Enable = 'on';
            app.InitialSamplesLabel.Text = sprintf('Loaded %d samples in %.1fs.', size(app.box,4), elapsed);
            app.ComputePCAButton.Enable = 'on';
            app.NumberofclustersEditFieldLabel.Enable = 'on';
            app.NumberofclustersEditField.Enable = 'on';
            app.ComputeclustersButton.Enable = 'on';
        end

        % Button pushed function: PlotvarianceexplainedButton
        function PlotvarianceexplainedButtonPushed(app, event)
            fig = figure;
            plotExplainedVar(app.pcs)
            fig.Position(3) = 800;
            zoom(fig, 'xon');
        end

        % Button pushed function: PloteigenmodesButton
        function PloteigenmodesButtonPushed(app, event)
            cols = 10;
            D = app.PCstoprojectdataontoEditField.Value;
            coeffs = imtile(arr2cell(app.pcs.coeff_box(:,:,:,1:D),4), cols);
            rows = size(coeffs,1) / size(app.pcs.coeff_box,1);
            if size(coeffs,3) > 1; coeffs = rescale(coeffs); end
            
            figure,figclosekey,figsize(1000,480)
            imagesc(coeffs)
            axis ij equal tight
            xticks([])
            dy = size(coeffs,1) / rows;
            yticks(dy/2:dy:size(coeffs,1))
            yticklabels(af(@(r)sprintf('PCs %d-%d', (r-1)*cols+1, r*cols), 1:rows))
            shortticks
            colormap(redblue)
            caxis([-0.75 0.75]*min(abs(caxis())))
            fontsize(18)
        end

        % Button pushed function: ComputeclustersButton
        function ComputeclustersButtonPushed(app, event)
            % Params
            usePCs = app.UsetheseprojectionsforclusteringCheckBox.Value && ~isempty(app.pcs);
            k = app.NumberofclustersEditField.Value;
            
            % Cluster
            t0 = stic;
            if usePCs
                D = app.PCstoprojectdataontoEditField.Value;
                [app.G, centroids] = kmeans(app.pcs.score(:,1:D),k,'Replicates',5);
                centroids = centroids * app.pcs.coeff(:,1:D)';
            else
                [app.G, centroids] = kmeans(stack2vecs(single(app.box)),k,'Replicates',5);
            end
            app.cluster_centroids = vecs2stack(centroids, size(app.box(:,:,:,1)));
            
            % Update GUI
            elapsed = stoc(t0);
            app.ClusteringLabel.Enable = 'on';
            minN = min(grpstats(app.G,app.G,'numel'));
            app.ClusteringLabel.Text = sprintf('Clustered samples in %.1fs. Smallest: n = %d', elapsed, minN);
            app.PlotclustercentroidsButton.Enable = 'on';
            app.PlotdistributionButton.Enable = 'on';
            app.SamplesperclusterEditFieldLabel.Enable = 'on';
            app.SamplesperclusterEditField.Enable = 'on';
            app.SamplesperclusterEditField.Limits = [1, minN];
            app.SamplesperclusterEditField.Value = min(app.SamplesperclusterEditField.Value, minN);
            app.SampleorderLabel.Enable = 'on';
            app.ClusterorderButton.Enable = 'on';
            app.ShuffledButton.Enable = 'on';
            app.GenerateddatasetLabel.Enable = 'on';
            app.SaveButton.Enable = 'on';
            app.PreviewButton.Enable = 'on';
        end

        % Button pushed function: PlotclustercentroidsButton
        function PlotclustercentroidsButtonPushed(app, event)
            cols = 5;
            
            C = imtile(arr2cell(app.cluster_centroids,4), cols);
            rows = size(C,1) / size(app.cluster_centroids,1);
            
            figure,figclosekey,figsize(1000,480)
            imagesc(C);
            axis ij equal tight
            xticks([]);
            dy = size(C,1) / rows;
            yticks(dy/2:dy:size(C,1))
            yticklabels(af(@(r)sprintf('Clusters %d-%d', (r-1)*cols+1, r*cols), 1:rows))
            shortticks;
            if all(C(:) > 0)
                colormap(gray);
                caxis([0 max(C(:))]);
            else
                colormap(redblue);
                caxis([-0.75 0.75]*min(abs(caxis())));
            end
            fontsize(18);
        end

        % Button pushed function: PlotdistributionButton
        function PlotdistributionButtonPushed(app, event)
            figure,figclosekey,figsize(800,420)
            h = histogram(app.G,'BinMethod','integers');
            xticks(1:max(app.G))
            xlabel('Clusters')
            ylabel('Count')
            grid on
            fontsize(16)
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            % Select output path
            try
                savePath = uibrowse('*.h5',[],'Save sampled dataset','savefile');
            catch
                return
            end
            
            % Sample
            n = app.SamplesperclusterEditField.Value;
            idx = app.sampleOverClusters(app.G, n);
            if app.ButtonGroup.SelectedObject == app.ShuffledButton
                idx = idx(randperm(numel(idx)));
            end
            
            % Extract data for saving
            idx = idx(:);
            box = app.box(:,:,:,idx);
            G = app.G(idx);
            dataIdx = app.dataIdx(idx);
            exptID = app.exptID(idx);
            boxPaths = strjoin(app.boxPaths,'\n');
            
            usePCs = app.UsetheseprojectionsforclusteringCheckBox.Value && ~isempty(app.pcs);
            if usePCs
                pcs = app.pcs;
                D = app.PCstoprojectdataontoEditField.Value;
                pcs.score = pcs.score(idx,1:D);
                pcs.coeff = pcs.coeff(:,1:D);
                pcs.coeff_box = pcs.coeff_box(:,:,:,1:D);
            end
            
            k = app.NumberofclustersEditField.Value;
            cluster_centroids = app.cluster_centroids;
            
            % Save
            t0 = stic;
            if exists(savePath); delete(savePath); end
            sz = size(box);
            h5save(savePath,box,'/box','ChunkSize',[sz(1:3) 1],'Deflate',1)
            h5save(savePath,exptID)
            h5save(savePath,dataIdx)
            h5writeatt(savePath,'/','boxPaths',boxPaths)
            if usePCs
                h5savegroup(savePath,pcs,'/pca')
            end
            h5savegroup(savePath,varstruct(k, G, cluster_centroids),'/clusters')
            stocf(t0, 'Saved: %s', savePath)
            get_filesize(savePath)
        end

        % Button pushed function: PreviewButton
        function PreviewButtonPushed(app, event)
            n = app.SamplesperclusterEditField.Value;
            idx = app.sampleOverClusters(app.G, n);
            if app.ButtonGroup.SelectedObject == app.ShuffledButton
                idx = randperm(idx);
            end
            
            vplay(app.box(:,:,:,idx))
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ClustersamplingUIFigure = uifigure;
            app.ClustersamplingUIFigure.Name = 'Cluster sampling';
            app.SelectdataPanel = uipanel(app.ClustersamplingUIFigure);
            app.SelectdataPanel.Title = '1. Select data';
            app.AddfileButton = uibutton(app.SelectdataPanel, 'push');
            app.AddfileButton.ButtonPushedFcn = createCallbackFcn(app, @AddfileButtonPushed, true);
            app.AddfileButton.Text = 'Add file...';
            app.AddfolderButton = uibutton(app.SelectdataPanel, 'push');
            app.AddfolderButton.ButtonPushedFcn = createCallbackFcn(app, @AddfolderButtonPushed, true);
            app.AddfolderButton.Text = 'Add folder...';
            app.RemoveselectedButton = uibutton(app.SelectdataPanel, 'push');
            app.RemoveselectedButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveselectedButtonPushed, true);
            app.RemoveselectedButton.Text = 'Remove selected';
            app.DatasetstatisticsPanel = uipanel(app.SelectdataPanel);
            app.DatasetstatisticsPanel.Title = 'Dataset statistics';
            app.NumberofframesNALabel = uilabel(app.DatasetstatisticsPanel);
            app.NumberofframesNALabel.Text = 'Number of frames: N/A';
            app.ImagesizeNALabel = uilabel(app.DatasetstatisticsPanel);
            app.ImagesizeNALabel.Text = 'Image size: N/A';
            app.NumberofvideosNALabel = uilabel(app.DatasetstatisticsPanel);
            app.NumberofvideosNALabel.Text = 'Number of videos: N/A';
            app.UITable = uitable(app.SelectdataPanel);
            app.UITable.ColumnName = {''; 'Path'; 'Dataset'; 'Shape'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [true false true false];
            app.SelectallButton = uibutton(app.SelectdataPanel, 'push');
            app.SelectallButton.ButtonPushedFcn = createCallbackFcn(app, @SelectallButtonPushed, true);
            app.SelectallButton.Text = 'Select all';
            app.ClustersamplingPanel = uipanel(app.ClustersamplingUIFigure);
            app.ClustersamplingPanel.Title = '2. Cluster sampling';
            app.PCAPreprocessingPanel = uipanel(app.ClustersamplingPanel);
            app.PCAPreprocessingPanel.Title = 'PCA Preprocessing';
            app.ComputePCAButton = uibutton(app.PCAPreprocessingPanel, 'push');
            app.ComputePCAButton.ButtonPushedFcn = createCallbackFcn(app, @ComputePCAButtonPushed, true);
            app.ComputePCAButton.Enable = 'off';
            app.ComputePCAButton.Text = 'Compute PCA';
            app.UsetheseprojectionsforclusteringCheckBox = uicheckbox(app.PCAPreprocessingPanel);
            app.UsetheseprojectionsforclusteringCheckBox.Enable = 'off';
            app.UsetheseprojectionsforclusteringCheckBox.Text = 'Use these projections for clustering';
            app.UsetheseprojectionsforclusteringCheckBox.Value = true;
            app.PlotvarianceexplainedButton = uibutton(app.PCAPreprocessingPanel, 'push');
            app.PlotvarianceexplainedButton.ButtonPushedFcn = createCallbackFcn(app, @PlotvarianceexplainedButtonPushed, true);
            app.PlotvarianceexplainedButton.Enable = 'off';
            app.PlotvarianceexplainedButton.Text = 'Plot variance explained';
            app.PCstoprojectdatadowntoEditFieldLabel = uilabel(app.PCAPreprocessingPanel);
            app.PCstoprojectdatadowntoEditFieldLabel.Enable = 'off';
            app.PCstoprojectdatadowntoEditFieldLabel.Text = 'PCs to project data down to:';
            app.PCstoprojectdataontoEditField = uieditfield(app.PCAPreprocessingPanel, 'numeric');
            app.PCstoprojectdataontoEditField.Limits = [1 Inf];
            app.PCstoprojectdataontoEditField.Enable = 'off';
            app.PCstoprojectdataontoEditField.Value = 50;
            app.PloteigenmodesButton = uibutton(app.PCAPreprocessingPanel, 'push');
            app.PloteigenmodesButton.ButtonPushedFcn = createCallbackFcn(app, @PloteigenmodesButtonPushed, true);
            app.PloteigenmodesButton.Enable = 'off';
            app.PloteigenmodesButton.Text = 'Plot eigenmodes';
            app.PCALabel = uilabel(app.PCAPreprocessingPanel);
            app.PCALabel.Enable = 'off';
            app.PCALabel.Text = 'Press ''Compute PCA'' to enable these options.';
            app.ClusteringPanel = uipanel(app.ClustersamplingPanel);
            app.ClusteringPanel.Title = 'Clustering';
            app.PlotclustercentroidsButton = uibutton(app.ClusteringPanel, 'push');
            app.PlotclustercentroidsButton.ButtonPushedFcn = createCallbackFcn(app, @PlotclustercentroidsButtonPushed, true);
            app.PlotclustercentroidsButton.Enable = 'off';
            app.PlotclustercentroidsButton.Text = 'Plot cluster centroids';
            app.NumberofclustersEditFieldLabel = uilabel(app.ClusteringPanel);
            app.NumberofclustersEditFieldLabel.Enable = 'off';
            app.NumberofclustersEditFieldLabel.Text = 'Number of clusters:';
            app.NumberofclustersEditField = uieditfield(app.ClusteringPanel, 'numeric');
            app.NumberofclustersEditField.Limits = [1 Inf];
            app.NumberofclustersEditField.ValueDisplayFormat = '%.0f';
            app.NumberofclustersEditField.Enable = 'off';
            app.NumberofclustersEditField.Value = 5;
            app.ComputeclustersButton = uibutton(app.ClusteringPanel, 'push');
            app.ComputeclustersButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeclustersButtonPushed, true);
            app.ComputeclustersButton.Enable = 'off';
            app.ComputeclustersButton.Text = 'Compute clusters';
            app.ClusteringLabel = uilabel(app.ClusteringPanel);
            app.ClusteringLabel.Enable = 'off';
            app.ClusteringLabel.Text = 'Press ''Compute clusters'' to enable these options.';
            app.PlotdistributionButton = uibutton(app.ClusteringPanel, 'push');
            app.PlotdistributionButton.ButtonPushedFcn = createCallbackFcn(app, @PlotdistributionButtonPushed, true);
            app.PlotdistributionButton.Enable = 'off';
            app.PlotdistributionButton.Text = 'Plot distribution';
            app.ButtonGroup = uibuttongroup(app.ClusteringPanel);
            app.ClusterorderButton = uiradiobutton(app.ButtonGroup);
            app.ClusterorderButton.Enable = 'off';
            app.ClusterorderButton.Text = 'Cluster order';
            app.ClusterorderButton.Value = true;
            app.ShuffledButton = uiradiobutton(app.ButtonGroup);
            app.ShuffledButton.Enable = 'off';
            app.ShuffledButton.Text = 'Shuffled';
            app.SaveButton = uibutton(app.ClusteringPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Enable = 'off';
            app.SaveButton.Text = 'Save...';
            app.SamplesperclusterEditFieldLabel = uilabel(app.ClusteringPanel);
            app.SamplesperclusterEditFieldLabel.Enable = 'off';
            app.SamplesperclusterEditFieldLabel.Text = 'Samples per cluster:';
            app.SamplesperclusterEditField = uieditfield(app.ClusteringPanel, 'numeric');
            app.SamplesperclusterEditField.Limits = [1 Inf];
            app.SamplesperclusterEditField.ValueDisplayFormat = '%.0f';
            app.SamplesperclusterEditField.Enable = 'off';
            app.SamplesperclusterEditField.Value = 150;
            app.SampleorderLabel = uilabel(app.ClusteringPanel);
            app.SampleorderLabel.Enable = 'off';
            app.SampleorderLabel.Text = 'Sample order:';
            app.PreviewButton = uibutton(app.ClusteringPanel, 'push');
            app.PreviewButton.ButtonPushedFcn = createCallbackFcn(app, @PreviewButtonPushed, true);
            app.PreviewButton.Enable = 'off';
            app.PreviewButton.Text = 'Preview';
            app.GenerateddatasetLabel = uilabel(app.ClusteringPanel);
            app.GenerateddatasetLabel.Enable = 'off';
            app.GenerateddatasetLabel.Text = 'Generated dataset:';
            app.SamplespervideoEditFieldLabel = uilabel(app.ClustersamplingPanel);
            app.SamplespervideoEditFieldLabel.Text = 'Samples per video:';
            app.SamplespervideoEditField = uieditfield(app.ClustersamplingPanel, 'numeric');
            app.SamplespervideoEditField.Limits = [1 Inf];
            app.SamplespervideoEditField.ValueDisplayFormat = '%.0f';
            app.SamplespervideoEditField.Value = 1000;
            app.LoadsamplesButton = uibutton(app.ClustersamplingPanel, 'push');
            app.LoadsamplesButton.ButtonPushedFcn = createCallbackFcn(app, @LoadsamplesButtonPushed, true);
            app.LoadsamplesButton.Text = 'Load samples';
            app.InitialSamplesLabel = uilabel(app.ClustersamplingPanel);
            app.InitialSamplesLabel.Enable = 'off';
            app.InitialSamplesLabel.Text = 'Load initial samples to enable options below.';
            app.ParallelCheckBox = uicheckbox(app.ClustersamplingPanel);
            app.ParallelCheckBox.Text = 'Parallel';
        end
    end

    methods (Access = public)

        % Construct app
        function app = cluster_sample

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ClustersamplingUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ClustersamplingUIFigure)
        end
    end
end