classdef create_skeleton < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CreateskeletonUIFigure      matlab.ui.Figure
        SelectreferenceimagePanel   matlab.ui.container.Panel
        ChoosefromworkspaceListBoxLabel  matlab.ui.control.Label
        ChoosefromworkspaceListBox  matlab.ui.control.ListBox
        ImportfromworkspaceButton   matlab.ui.control.Button
        RefreshButton               matlab.ui.control.Button
        LoadfromimagefileButton     matlab.ui.control.Button
        orLabel                     matlab.ui.control.Label
        orLabel_2                   matlab.ui.control.Label
        LoadexistingskeletonButton  matlab.ui.control.Button
        DefineskeletonPanel         matlab.ui.container.Panel
        UITable                     matlab.ui.control.Table
        SelectallButton             matlab.ui.control.Button
        DeleteselectedButton        matlab.ui.control.Button
        AddnewbodypartButton        matlab.ui.control.Button
        SaveskeletontofileButton    matlab.ui.control.Button
    end


    properties (Access = public)
        imgFig = []
        imgAx
        imgObj
        markers = []
        refI
        segments = []
    end

    methods (Access = private)
    
        function loadRefImg(app, img)
            app.refI = img;
            
            if ~isfig(app.imgFig)
                app.imgFig = figure('CloseRequestFcn',@(h,~)app.CreateskeletonUIFigureCloseRequest(), ...
                    'NumberTitle',false,'Name','Select default body part positions');
            else
                clf(app.imgFig)
            end
            app.imgAx = axes(app.imgFig);
            
            app.imgObj = imshow(img,'Parent',app.imgAx,'Border','tight','InitialMagnification',400);
            hold(app.imgAx,'on');
            
            app.imgFig.Position(1) = sum(app.CreateskeletonUIFigure.Position([1 3])) + 5; % align to the right
            app.imgFig.Position(2) = app.CreateskeletonUIFigure.Position(2); % align bottom
            
            app.drawMarkers();
        end
        
        function drawMarkers(app)
            if ~isempty(app.markers); delete(app.markers); end
            if ~isempty(app.segments); delete(app.segments); end
            if size(app.UITable.Data,1) < 1; return; end
            
            % Get data
            T = app.getTable();
            
            % Draw segments
            ccs = app.getSegments();
            cmap = lines(numel(ccs));
            app.segments = gobjects(numel(ccs),1);
            for i = 1:numel(ccs)
                xd = T.x(ccs{i});
                yd = T.y(ccs{i});
                app.segments(i) = plot(xd,yd,'-','Color',cmap(i,:),'LineWidth',2,'Parent',app.imgAx);
                app.segments(i).UserData.nodes = ccs{i};
            end
            
            % Draw markers
            app.markers = gobjects(height(T),1);
            for i = 1:numel(app.markers)
                app.markers(i) = plot(T.x(i),T.y(i), 'ro', 'MarkerSize',10, 'LineWidth', 2, 'Parent',app.imgAx);
                
                app.markers(i).UserData.i = i;
                app.markers(i).UserData.data = T(i,:);
            end
            
            % Make draggable with callbacks
            draggable(app.markers,@(h)app.markerMoving(h),'endFcn',@(h)app.markerMoved(h));
        end
    
        function segments = getSegments(app)
            if size(app.UITable,1) < 1; return; end
            
            % Get data
            T = app.getTable();
            
            % Add every edge as a segment (TODO: assume it's a DAG or add UI to edit)
            segments = {};
            for i = 1:height(T)
                if ~isempty(T.parent{i})
                    j = find(T.name == string(T.parent{i}),1);
                    if ~isempty(j)
                        segments{end+1} = [j, i];
                    end
                end
            end
            
            % Build directed graph of skeleton
%             G = digraph([],[],[],height(T));
%             for i = 1:height(T)
%                 if ~isempty(T.parent{i})
%                     j = find(T.name == string(T.parent{i}),1);
%                     if ~isempty(j)
%                         G = addedge(G, j, i);
%                     end
%                 end
%             end
            
            % Find connected components
%             segments = conncomp(G,'Type','weak','OutputForm','cell');
            
            % Exclude singletons
%             segments = segments(cellfun(@numel,segments) > 1);
        end
    
        function markerMoving(app, marker)
            % Update segments
            for i = 1:numel(app.segments)
                isMovingMarker = app.segments(i).UserData.nodes == marker.UserData.i;
                if any(isMovingMarker)
                    app.segments(i).XData(isMovingMarker) = marker.XData(1);
                    app.segments(i).YData(isMovingMarker) = marker.YData(1);
                end
            end
            
            % Update figure title
            name_idx = app.UITable.ColumnName == "name";
            app.imgFig.Name = ['Moving: ' app.UITable.Data{marker.UserData.i,name_idx}];
        end
        
        function markerMoved(app, marker)
            % Update table
            x_idx = app.UITable.ColumnName == "x";
            y_idx = app.UITable.ColumnName == "y";
            app.UITable.Data{marker.UserData.i,x_idx} = marker.XData(1);
            app.UITable.Data{marker.UserData.i,y_idx} = marker.YData(1);
            
            % Update figure title
            name_idx = app.UITable.ColumnName == "name";
            app.imgFig.Name = ['Last moved: ' app.UITable.Data{marker.UserData.i,name_idx}];
        end
        
        function T = getTable(app)
            if size(app.UITable,1) < 1; return; end
            
            % Exclude checkbox column
            cols = app.UITable.ColumnName;
            cols_idx = ~cellfun(@isempty,cols);
            cols = cols(cols_idx);
            
            % Convert to table and return
            T = cell2table(app.UITable.Data(:,cols_idx),'VariableNames',cols);
        end
        
        function checkTable(app)
            % Check if the UI table has invalid entries (non-existing parents, etc)
            name_idx = app.UITable.ColumnName == "name";
            parent_idx = app.UITable.ColumnName == "parent";
            
            names = app.UITable.Data(:,name_idx);
            parents = app.UITable.Data(:,parent_idx);
            
            for i = numel(names):-1:1
                % check for empty names
                if isempty(names{i})
                    names{i} = get_new_string('body_part',names);
                end
                
                % check for duplicate names and rename them
                names{i} = get_new_string(names{i},names(setdiff(1:numel(names),i)));
            end
            
            % check for parents that don't exist or self reference
            for i = 1:numel(parents)
                if ~any(strcmp(parents{i}, names)) || isequal(parents{i}, names{i})
                    parents{i} = '';
                end
            end
            
            % TODO: update parent references when renaming a body part (add to edit callback)
            
            % Save to UI
            app.UITable.Data(:,name_idx) = names;
            app.UITable.Data(:,parent_idx) = parents;
            
        end
        
        function findWSImages(app)
            % Finds variables in the workspace that match the criteria of an image and updates the listbox
            
            vars = evalin('base','whos');
            numericTypes = {'single','double','int8','uint8','int16','uint16','int32','uint32','int64','uint64'};
            isNum = ismember({vars.class},numericTypes);
            is2d = cellfun(@(x)numel(x) == 2 & min(x) > 1,{vars.size});
            isRGB = cellfun(@(x)numel(x) == 3,{vars.size});
            isRGB(isRGB) = cellfun(@(x)x(3) == 3, {vars(isRGB).size});
            imgVars = vars(isNum & (is2d | isRGB));
            app.ChoosefromworkspaceListBox.Items = af(@(x)sprintf('%s %s (%s)', x.name, mat2str(x.size), x.class), imgVars);
            app.ChoosefromworkspaceListBox.ItemsData = {imgVars.name};
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UITable.ColumnName = {'', 'name'      'x' 'y'          'parent'        'description'};
            app.UITable.Data = {
                false, 'head'      148    97     ''              'tip of head (between antennae)'
                false, 'neck'      128    96     'head'          'connects head and thorax'
                false, 'thorax'    99    96      'neck'          'mesothoracic phragma (connects thorax and abdomen)'
                false, 'abdomen'   56    96      'thorax'        'tip of abdomen'
                false, 'forelegR1' 120   112     ''              'right foreleg / thorax-coxa'
                false, 'forelegR2' 128   119     'forelegR1'     'right foreleg / coxa-femur'
                false, 'forelegR3' 129   127     'forelegR2'     'right foreleg / femur-tibia'
                false, 'forelegR4' 133   139     'forelegR3'     'right foreleg / tarsus tip'
            
                false, 'midlegR1'  104   115     ''              'right midleg / thorax-coxa'
                false, 'midlegR2'  98   124      'midlegR1'      'right midleg / coxa-femur'
                false, 'midlegR3'  103   128     'midlegR2'      'right midleg / femur-tibia'
                false, 'midlegR4'  101   146     'midlegR3'      'right midleg / tarsus tip'
                
                false, 'hindlegR1' 87   111      ''              'right hindleg / thorax-coxa'
                false, 'hindlegR2' 69   117      'hindlegR1'     'right hindleg / coxa-femur'
                false, 'hindlegR3' 64   112      'hindlegR2'     'right hindleg / femur-tibia'
                false, 'hindlegR4' 45   121      'hindlegR3'     'right hindleg / tarsus tip'
                
                false, 'forelegL1' 124    81     ''              'left foreleg / thorax-coxa'
                false, 'forelegL2' 141    77     'forelegL1'     'left foreleg / coxa-femur'
                false, 'forelegL3' 151    74     'forelegL2'     'left foreleg / femur-tibia'
                false, 'forelegL4' 161    65     'forelegL3'     'left foreleg / tarsus tip'
            
                false, 'midlegL1'  106    78     ''              'left midleg / thorax-coxa'
                false, 'midlegL2'  104    66     'midlegL1'      'left midleg / coxa-femur'
                false, 'midlegL3'  113    64     'midlegL2'      'left midleg / femur-tibia'
                false, 'midlegL4'  118    46     'midlegL3'      'left midleg / tarsus tip'
                
                false, 'hindlegL1' 87    78      ''              'left hindleg / thorax-coxa'
                false, 'hindlegL2' 79    72      'hindlegL1'     'left hindleg / coxa-femur'
                false, 'hindlegL3' 74    79      'hindlegL2'     'left hindleg / femur-tibia'
                false, 'hindlegL4' 55    68      'hindlegL3'     'left hindleg / tarsus tip'
                };
            
            % Populate workspace images listbox
            app.findWSImages();
             
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            app.checkTable();
            app.drawMarkers();
        end

        % Button pushed function: AddnewbodypartButton
        function AddnewbodypartButtonPushed(app, event)
            app.UITable.Data(end+1,:) = {false, 'body_part', 10, 10, '', ''};
            app.checkTable();
            app.drawMarkers();
        end

        % Button pushed function: DeleteselectedButton
        function DeleteselectedButtonPushed(app, event)
           selected = [app.UITable.Data{:,1}];
           if any(selected)
               app.UITable.Data = app.UITable.Data(~selected,:);
               app.checkTable();
               app.drawMarkers();
           end
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
%             printf('UITableCellSelection(app, event)')
%             event
        end

        % Button pushed function: SelectallButton
        function SelectallButtonPushed(app, event)
            if all([app.UITable.Data{:,1}])
                app.UITable.Data(:,1) = {false};
            else
                app.UITable.Data(:,1) = {true};
            end
        end

        % Button pushed function: LoadfromimagefileButton
        function LoadfromimagefileButtonPushed(app, event)
            try
                imgPath = uibrowse(ext2filter_spec({'jpg','jpeg','png','tif'}),[],'Select a reference image');
                I = imread(imgPath);
                app.loadRefImg(I);
            catch
            end
        end

        % Button pushed function: ImportfromworkspaceButton
        function ImportfromworkspaceButtonPushed(app, event)
            if ~isempty(app.ChoosefromworkspaceListBox.Value)
                I = evalin('base',app.ChoosefromworkspaceListBox.Value);
                app.loadRefImg(I);
            end
        end

        % Close request function: CreateskeletonUIFigure
        function CreateskeletonUIFigureCloseRequest(app, event)
            if isfig(app.imgFig); delete(app.imgFig); end
            delete(app)
        end

        % Button pushed function: SaveskeletontofileButton
        function SaveskeletontofileButtonPushed(app, event)
            if isempty(app.refI); return; end
            
            try
                savePath = uibrowse('*.mat',[],'Save skeleton file','savefile');
            catch
                return
            end
            
            joints = app.getTable();
            joints.pos = [joints.x joints.y];
            joints.idx = vert(1:height(joints));
            
            pos = joints.pos;
            nodes = joints.name;
            
            edges = app.getSegments();
            edges = edges(cellfun(@numel,edges) == 2);
            if numel(edges) > 0
                cmap = lines(numel(edges));
                segments = cell(numel(edges),3);
                for i = 1:numel(edges)
                    segments{i,1} = {edges{i}};
                    segments{i,2} = joints.name(edges{i});
                    segments{i,3} = {cmap(i,:)};
                end
                segments = cell2table(segments,'VariableNames',{'joints_idx','joints','color'});
            else
                segments = table({},{},{},'VariableNames',{'joints_idx','joints','color'});
            end
            
            edges = cellcat(edges,1);
            
            refI = app.refI;
            refPath = '';
            refIdx = [];
            
            save(savePath, 'joints', 'refPath', 'refIdx', 'refI', 'segments', 'nodes', 'edges', 'pos')
            printf('Saved: %s', savePath)
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            app.findWSImages();
        end

        % Button pushed function: LoadexistingskeletonButton
        function LoadexistingskeletonButtonPushed(app, event)
            try
                matPath = uibrowse(ext2filter_spec({'mat'}),[],'Select an existing skeleton MAT file...');
                skel = load(matPath);
                data = cell(numel(skel.nodes),size(app.UITable.Data,2));
                for i = 1:size(data,1)
                    s = table2struct(skel.joints(i,:));
                    data(i,:) = {false, s.name, s.x, s.y, s.parent, s.description};
                end
                app.UITable.Data = data;
                app.loadRefImg(skel.refI);
            catch
                % TODO: informative error messages
                % - Loaded MAT file with no skeleton data
                % - Clicked cancel
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CreateskeletonUIFigure = uifigure;
            app.CreateskeletonUIFigure.Name = 'Create skeleton';
            app.CreateskeletonUIFigure.CloseRequestFcn = createCallbackFcn(app, @CreateskeletonUIFigureCloseRequest, true);
            app.SelectreferenceimagePanel = uipanel(app.CreateskeletonUIFigure);
            app.SelectreferenceimagePanel.Title = '1. Select reference image';
            app.ChoosefromworkspaceListBoxLabel = uilabel(app.SelectreferenceimagePanel);
            app.ChoosefromworkspaceListBoxLabel.Text = 'Choose from workspace:';
            app.ChoosefromworkspaceListBox = uilistbox(app.SelectreferenceimagePanel);
            app.ChoosefromworkspaceListBox.Items = {};
            app.ChoosefromworkspaceListBox.Value = {};
            app.ImportfromworkspaceButton = uibutton(app.SelectreferenceimagePanel, 'push');
            app.ImportfromworkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @ImportfromworkspaceButtonPushed, true);
            app.ImportfromworkspaceButton.Text = 'Import from workspace';
            app.RefreshButton = uibutton(app.SelectreferenceimagePanel, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.LoadfromimagefileButton = uibutton(app.SelectreferenceimagePanel, 'push');
            app.LoadfromimagefileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadfromimagefileButtonPushed, true);
            app.LoadfromimagefileButton.Text = 'Load from image file...';
            app.orLabel = uilabel(app.SelectreferenceimagePanel);
            app.orLabel.Text = 'or';
            app.orLabel_2 = uilabel(app.SelectreferenceimagePanel);
            app.orLabel_2.Text = 'or';
            app.LoadexistingskeletonButton = uibutton(app.SelectreferenceimagePanel, 'push');
            app.LoadexistingskeletonButton.ButtonPushedFcn = createCallbackFcn(app, @LoadexistingskeletonButtonPushed, true);
            app.LoadexistingskeletonButton.Text = 'Load existing skeleton...';
            app.DefineskeletonPanel = uipanel(app.CreateskeletonUIFigure);
            app.DefineskeletonPanel.Title = '2. Define skeleton';
            app.UITable = uitable(app.DefineskeletonPanel);
            app.UITable.ColumnName = {''; 'name'; 'x'; 'y'; 'parent'; 'description'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.SelectallButton = uibutton(app.DefineskeletonPanel, 'push');
            app.SelectallButton.ButtonPushedFcn = createCallbackFcn(app, @SelectallButtonPushed, true);
            app.SelectallButton.Text = 'Select all';
            app.DeleteselectedButton = uibutton(app.DefineskeletonPanel, 'push');
            app.DeleteselectedButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteselectedButtonPushed, true);
            app.DeleteselectedButton.Text = 'Delete selected';
            app.AddnewbodypartButton = uibutton(app.DefineskeletonPanel, 'push');
            app.AddnewbodypartButton.ButtonPushedFcn = createCallbackFcn(app, @AddnewbodypartButtonPushed, true);
            app.AddnewbodypartButton.Text = 'Add new body part';
            app.SaveskeletontofileButton = uibutton(app.DefineskeletonPanel, 'push');
            app.SaveskeletontofileButton.ButtonPushedFcn = createCallbackFcn(app, @SaveskeletontofileButtonPushed, true);
            app.SaveskeletontofileButton.Text = 'Save skeleton to file...';
        end
    end

    methods (Access = public)

        % Construct app
        function app = create_skeleton

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CreateskeletonUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CreateskeletonUIFigure)
        end
    end
end